classdef Animate < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                     matlab.ui.Figure
    PauseDurationEditFieldLabel  matlab.ui.control.Label
    PauseDurationEditField       matlab.ui.control.NumericEditField
    SecondsLabel                 matlab.ui.control.Label
    StartButton                  matlab.ui.control.Button
    UIAxes                       matlab.ui.control.UIAxes
    StopButton                   matlab.ui.control.Button
    NumberofStepsEditFieldLabel  matlab.ui.control.Label
    NumberofStepsEditField       matlab.ui.control.NumericEditField
  end

  properties (Access = public)
    stopAnimation = false; % Stop button state
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)
      view(app.UIAxes,[1,1,1]);
      grid(app.UIAxes,'grid','on')
      zlabel(app.UIAxes,'Z');
      app.stopAnimation = false;
    end

    % Button pushed function: StartButton
    function StartButtonPushed(app, event)
      %% Box design
      x   = 3; y = 2; z = 1;
      f   = [2 3 6;3 7 6;3 4 8;3 8 7;4 5 8;4 1 5;2 6 5;2 5 1;1 3 2;1 4 3;5 6 7;5 7 8];
      v   = [-x x x -x -x x x -x; -y -y y y -y -y  y  y; -z -z -z -z z z z z]'/2;
      p   = patch(app.UIAxes,'vertices',v,'faces',f,'facecolor',[0.5 0.5 0.5],...
          'linestyle','none','facelighting','gouraud');
      set(app.UIAxes,'DataAspectRatio',[1 1 1],'DataAspectRatioMode','manual')
      axis(app.UIAxes,[-8 8 -8 8 -8 8])
      light(app.UIAxes,'position',[0 0 1])

      % Animate
      n = app.NumberofStepsEditField.Value;
      a = linspace(0,8*pi,n); c = cos(a); s = sin(a);
      
      app.stopAnimation = false;
      for k = 1:n
        b   = [c(k) 0 s(k);0 1 0;-s(k) 0 c(k)];
        vK  = (b*v')';
        set(p,'vertices',vK);
        pause(app.PauseDurationEditField.Value); 
        if( app.stopAnimation )
          break
        end
      end
      delete(p);
    end

    % Button pushed function: StopButton
    function StopButtonPushed(app, event)
      app.stopAnimation = true;
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.PauseDurationEditFieldLabel = uilabel(app.UIFigure);
      app.PauseDurationEditFieldLabel.Text = 'Pause Duration';
      app.PauseDurationEditField = uieditfield(app.UIFigure, 'numeric');
      app.SecondsLabel = uilabel(app.UIFigure);
      app.SecondsLabel.Text = 'Seconds';
      app.StartButton = uibutton(app.UIFigure, 'push');
      app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
      app.StartButton.Text = 'Start';
      app.UIAxes = uiaxes(app.UIFigure);
      title(app.UIAxes, 'Animation')
      xlabel(app.UIAxes, 'X')
      ylabel(app.UIAxes, 'Y')
      app.StopButton = uibutton(app.UIFigure, 'push');
      app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
      app.StopButton.Text = 'Stop';
      app.NumberofStepsEditFieldLabel = uilabel(app.UIFigure);
      app.NumberofStepsEditFieldLabel.Text = 'Number of Steps';
      app.NumberofStepsEditField = uieditfield(app.UIFigure, 'numeric');
      app.NumberofStepsEditField.Value = 1000;
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = Animate

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @startupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end