classdef DFGUI < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                  matlab.ui.Figure
    UIAxes                    matlab.ui.control.UIAxes
    UIAxes2                   matlab.ui.control.UIAxes
    ParametersPanel           matlab.ui.container.Panel
    DurationEditFieldLabel    matlab.ui.control.Label
    DurationEditField         matlab.ui.control.NumericEditField
    InputEditField_3Label     matlab.ui.control.Label
    InputEditField_3          matlab.ui.control.NumericEditField
    Tau1sEditFieldLabel       matlab.ui.control.Label
    Tau1sEditField            matlab.ui.control.NumericEditField
    Tau2sEditFieldLabel       matlab.ui.control.Label
    Tau2sEditField            matlab.ui.control.NumericEditField
    TimeStepsEditFieldLabel   matlab.ui.control.Label
    TimeStepsEditField        matlab.ui.control.NumericEditField
    FailuresPanel             matlab.ui.container.Panel
    TachometerFaultButton     matlab.ui.control.Button
    InputFaultButton          matlab.ui.control.Button
    TachometerEditFieldLabel  matlab.ui.control.Label
    TachometerEditField       matlab.ui.control.NumericEditField
    InputFaultEditFieldLabel  matlab.ui.control.Label
    InputFaultEditField       matlab.ui.control.NumericEditField
    CalculateButton           matlab.ui.control.Button
    ResetButton               matlab.ui.control.Button
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Button pushed function: CalculateButton
    function CalculateButtonPushed(app, event)
      % get the data from the app
      u         = app.InputEditField_3.Value;
      duration  = app.DurationEditField.Value;
      tachF     = app.TachometerEditField.Value;
      uF        = app.InputFaultEditField.Value;
      dT        = app.TimeStepsEditField.Value;
      tau1      = app.Tau1sEditField.Value;
      tau2      = app.Tau2sEditField.Value;  
      
      % Set up the model
      d         = RHSAirTurbine;
      dF        = DetectionFilter('initialize',d,[tau1;tau2],dT);    
 
      % Plotting array
      n         = ceil(duration/dT);
      xP        = zeros(4,n);
      t         = (0:n-1)*dT;
      x         = [0;0];
      
      for k = 1:n
        % Measurement vector including measurement failure
        y       = [x(1);tachF*x(2)]; % Sensor failure
        xP(:,k) = [x;dF.r];
 
        % Update the detection filter
        dF      = DetectionFilter('update',u,y,dF);
	  
        % Integrate one step
        d.u     = uF*u; % Actuator failure
        x       = RungeKutta( @RHSAirTurbine, t(k), x, dT, d );
      end
      
      [t,tL] = TimeLabel(t);

      % Plot the states and residuals
      axes(app.UIAxes)
      plot(app.UIAxes,t, xP(1:2,:) )
      xlabel(app.UIAxes,tL);
      legend(app.UIAxes,'p','\omega')
      
      axes(app.UIAxes2)
      plot(app.UIAxes2,t, xP(3:4,:) )
      xlabel(app.UIAxes2,tL);
      legend(app.UIAxes2,'r_p','r_{\omega}')

    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.UIAxes = uiaxes(app.UIFigure);
      title(app.UIAxes, 'States')
      xlabel(app.UIAxes, 'Time')
      ylabel(app.UIAxes, 'States')
      app.UIAxes2 = uiaxes(app.UIFigure);
      title(app.UIAxes2, 'Residuals')
      xlabel(app.UIAxes2, 'Time')
      ylabel(app.UIAxes2, 'Residuals')
      app.ParametersPanel = uipanel(app.UIFigure);
      app.ParametersPanel.Title = 'Parameters';
      app.DurationEditFieldLabel = uilabel(app.ParametersPanel);
      app.DurationEditFieldLabel.Text = 'Duration';
      app.DurationEditField = uieditfield(app.ParametersPanel, 'numeric');
      app.DurationEditField.Value = 1000;
      app.InputEditField_3Label = uilabel(app.ParametersPanel);
      app.InputEditField_3Label.Text = 'Input';
      app.InputEditField_3 = uieditfield(app.ParametersPanel, 'numeric');
      app.InputEditField_3.Value = 100;
      app.Tau1sEditFieldLabel = uilabel(app.ParametersPanel);
      app.Tau1sEditFieldLabel.Text = 'Tau 1 (s)';
      app.Tau1sEditField = uieditfield(app.ParametersPanel, 'numeric');
      app.Tau1sEditField.Value = 0.3;
      app.Tau2sEditFieldLabel = uilabel(app.ParametersPanel);
      app.Tau2sEditFieldLabel.Text = 'Tau 2 (s)';
      app.Tau2sEditField = uieditfield(app.ParametersPanel, 'numeric');
      app.Tau2sEditField.Value = 0.3;
      app.TimeStepsEditFieldLabel = uilabel(app.ParametersPanel);
      app.TimeStepsEditFieldLabel.Text = 'Time Step (s)';
      app.TimeStepsEditField = uieditfield(app.ParametersPanel, 'numeric');
      app.TimeStepsEditField.Value = 0.02;
      app.FailuresPanel = uipanel(app.UIFigure);
      app.FailuresPanel.Title = 'Failures';
      app.TachometerFaultButton = uibutton(app.FailuresPanel, 'push');
      app.TachometerFaultButton.Text = 'Tachometer Fault';
      app.InputFaultButton = uibutton(app.FailuresPanel, 'push');
      app.InputFaultButton.Text = 'Input Fault';
      app.TachometerEditFieldLabel = uilabel(app.FailuresPanel);
      app.TachometerEditFieldLabel.Text = 'Tachometer';
      app.TachometerEditField = uieditfield(app.FailuresPanel, 'numeric');
      app.TachometerEditField.Value = 1;
      app.InputFaultEditFieldLabel = uilabel(app.FailuresPanel);
      app.InputFaultEditFieldLabel.Text = 'Input Fault';
      app.InputFaultEditField = uieditfield(app.FailuresPanel, 'numeric');
      app.CalculateButton = uibutton(app.UIFigure, 'push');
      app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
      app.CalculateButton.Text = 'Calculate';
      app.ResetButton = uibutton(app.UIFigure, 'push');
      app.ResetButton.Text = 'Reset';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = DFGUI

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end